classdef imgRegMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout9                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        DataTab                     matlab.ui.container.Tab
        dataGrid                    matlab.ui.container.GridLayout
        dataButtonGrid              matlab.ui.container.GridLayout
        LoadfolderButton            matlab.ui.control.Button
        LoadfilesButton             matlab.ui.control.Button
        RemoveselectedrowsButton    matlab.ui.control.Button
        imageVersionDropDown        matlab.ui.control.DropDown
        RegisterimagesButton_2      matlab.ui.control.Button
        ResettransformsButton_2     matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        visibleData                 matlab.ui.control.Table
        ImageTab                    matlab.ui.container.Tab
        imageGrid                   matlab.ui.container.GridLayout
        GridLayout_corr             matlab.ui.container.GridLayout
        Label_corr                  matlab.ui.control.Label
        UIAxes_corr                 matlab.ui.control.UIAxes
        imageTable                  matlab.ui.control.Table
        imageButtonGrid             matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        AutoTab                     matlab.ui.container.Tab
        GridLayout_AutoMove         matlab.ui.container.GridLayout
        RecenterviewButton          matlab.ui.control.Button
        RegisterimagesButton        matlab.ui.control.Button
        ManualTab                   matlab.ui.container.Tab
        GridLayoutManualMove        matlab.ui.container.GridLayout
        UITableTransform            matlab.ui.control.Table
        UIAxesScrollManual          matlab.ui.control.UIAxes
        CtrlptsTab                  matlab.ui.container.Tab
        Label2                      matlab.ui.control.Label
        SelectionAmountButtonGroup  matlab.ui.container.ButtonGroup
        Spinner                     matlab.ui.control.Spinner
        UseallCheckBox              matlab.ui.control.CheckBox
        RemovingOldestSwitch        matlab.ui.control.Switch
        ResetcolorsCheckBox         matlab.ui.control.CheckBox
        ColorSortingSwitch          matlab.ui.control.Switch
        ResettransformsButton       matlab.ui.control.Button
        SaveButton_2                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        config struct %struct representing the config file
        process_load_data_table table %table needed to process additional functions
        previousPivotIndex int16 = 0 %index for changing pivot
        prevSelect logical = 0 %index for changing select related to pivot
        data table %Master table where everything is stored
        corr_maxVal double = NaN % Correlation imge text
        
        outputView imref2d %imgref to plot multiple recordings relative to each other
        %added selMemory to the app INIT or it hasn't yet done the startup
        %function
        selMemory % basicQueue = basicQueue(); memory of order in which images are selected, make it a property but init in initImage
        selMemState struct %struct to store memory state
        
        
        %You can change what the following columns appear as in the UI
        %the dictionaries contain key-value pairs, the key must remain the
        %same as that is what is referenced in the program, the value
        %(which is displayed) can be changed to whatever you would like
        colName = dictionary("pivot", 'Pivot',   "select", 'Select',   "manual", 'Manual',    "ID", "ID",    "name", 'Name',   "path", 'Path') %column names for visible data
        colType = dictionary("pivot", 'logical', "select", 'logical', "manual", 'logical', "ID", "double","name", 'string', "path", 'string')
        hColName = dictionary("tform", 'tForm',         "wImg", "warpedImg", "imgColor", "imgColor") %column names for hidden data
        hColType = dictionary("tform", 'affinetform2d', "wImg", "cell",      "imgColor", "double")
        viewsuffix = "_view";
        rawsuffix = "_raw";
        
        %BUG WITH ID , WHEN DELETING ROWS THE ID GETS CORRUPTED
        %EITHER MAKE THE ID JUST THE ROW NUMBER; SO IT CAN CHANGE FOR EACH
        %IMAGE WHEN ROWS ARE DELETED; OR ADD A CONSTANT THAT SLOWLY
        %ITERATES FOR EACH ROW ADDED
        
        %ADD ANOTHER TOGGLE SO I CAN PLOT RAW IMAGES IN THE PLOTTER MAYBE A
        %BUTTON SO IT JUST PLOTS A TWO IMAGE PAIR, i.e. the single selected
        %image and it's raw counterpart
        
        %BUGS WHEN REGISTERING WITHOUT A PIVOT
        %
        %
        % 
    end
    
    methods (Description= "init", Access = private)
        
        function initWorkingDir(app)
            excludedPaths = {'\.git', '\docs'};
            currentDir = pwd;
            
            allPaths = genpath(currentDir);
            allPaths = strsplit(allPaths, pathsep);
            allPaths = allPaths(~cellfun('isempty', allPaths));
            keepPaths = true(size(allPaths));
            
            for i = 1: length(excludedPaths)
                keepPaths = keepPaths & ~contains(allPaths, excludedPaths{i});
            end
            
            filteredPaths = allPaths(keepPaths);
            
            for i = 1: length(filteredPaths)
                addpath(filteredPaths{i});
            end
            
            disp('Paths added to MATLAB:');
            disp(filteredPaths');
        end
        
        function initUITables(app)
            %INIT VISIBLE TABLE
            VTCols = [values(app.colName)];
            VTWidth =    [55, 60, 65, 50, 100];
            VTEditable = [true, true, false, false, false, false, false];
            for i=1:length(VTCols)
                if i<= length(VTWidth)
                    addUIColumn(app, app.visibleData, VTCols(i), VTEditable(i), VTWidth(i));
                else
                    addUIColumn(app, app.visibleData, VTCols(i));
                end
            end
            
            %INIT IMAGE VIEWER TABLE
            imgCols = [app.colName("pivot"), app.colName("select"), app.colName("manual"), app.colName("ID"),app.colName("IDName")];
            imgWidth = {45, 55, 55, 16, 'auto'};
            imgEditable = [true, true, true, false, false];
            for i=1:length(imgCols)
                if i<= length(imgWidth)
                    addUIColumn(app, app.imageTable, imgCols(i), imgEditable(i), imgWidth{i});
                else
                    addUIColumn(app, app.imageTable, imgCols(i));
                end
            
            end
        
        end

        function initData(app)
            %INIT EXTRA COLUMN FUNCTIONS TABLE
            procColNames = {'output_cols', 'handles', 'input_cols'};
            procVarTypes = {'cell', 'cell', 'cell'};
            app.process_load_data_table = table('Size',[0,length(procColNames)],'VariableTypes',procVarTypes, 'VariableNames',procColNames);
            
            %INIT DATA TABLE
            colNames = [values(app.colName);values(app.hColName)];
            varTypes = [values(app.colType);values(app.hColType)];
            app.data = table('Size',[0,length(colNames)],'VariableTypes',varTypes, 'VariableNames',colNames);
            
            tempImages = cell(1, length(app.config.data.FetchDataCols));
            
            %ADD ADDITIONAL NAME COLS
            for i=1:length(app.config.data.ExtraColumns)
                ProcessConfigColumn(app, app.config.data.ExtraColumns{i});
            end
            %ADD ADDITIONAL IMAGE COLS
            for i=1:length(app.config.data.FetchDataCols)
                tempImages{i} = ProcessConfigColumn(app, app.config.data.FetchDataCols{i});
            end
            
            %RESOLVE USER DEFINED COLUMNS
            app.process_load_data_table = ResolveProcessTableOrder(app, colNames, app.process_load_data_table);
            
            for i=1:height(app.process_load_data_table)
                col = app.process_load_data_table.output_cols{i};
                addColumn(app, col);
                app.colName = insert(app.colName, col,  col);
                %EACH IMAGE HAS A VIEW, A NAME TO DISPLAY, AND RAW IMAGE
                %ITSELF
                if ismember(col, tempImages)
                    colView = col + app.viewsuffix;
                    colRaw = col + app.rawsuffix;
                    
                    addColumn(app, colView);
                    app.hColName = insert(app.hColName,  colView,  colView);
                    addColumn(app, colRaw);
                    app.hColName = insert(app.hColName,  colRaw,  colRaw);
                    
                    app.imageVersionDropDown.Items = [app.imageVersionDropDown.Items, {col}];
                    
                    %CHANGE THE NAME FOR PROCESS LOAD TABLE TO TARGET THE
                    %RAW ARRAY
                    app.process_load_data_table.output_cols{i} = colRaw;
                end
            end
            
            %ADD FINALISED COLUMNS
            initUITables(app);
        end
        
        function newTable = ResolveProcessTableOrder(app, existCol, oldTable)
            newTable = oldTable;
            newTable(:, :) = [];

            while height(oldTable) > 0
                noResolution = true;
                for i=1:height(oldTable)
                    if all(ismember(oldTable.input_cols{i}, existCol))
                        noResolution = false;
                        %add check for duplicates
                        if ~checkDuplicates(i)
                            newTable = [newTable; oldTable(i, :)];
                            existCol = [existCol; oldTable.output_cols{i}];
                        end
                        oldTable(i, :) = [];
                        break;
                    end
                end
                if noResolution
                    error("Can't resolve added columns. Check for circular inputs in the function inputs")
                end
            end
           
            for i=1:height(newTable)
                newTable.handles(i) = {str2func(newTable.handles{i})};
            end
            
            function bool = checkDuplicates(row)
                bool = false;
                if isequal(height(newTable), 0)
                    return
                elseif ismember(oldTable.handles{row}, string(newTable.handles))
                    bool = true;
                    return
                elseif ismember(oldTable.output_cols{row}, string(newTable.output_cols))
                    error("Column names must be different, please remove duplicated: %s", oldTable.output_cols{row});
                end
            end
        end

        function addUIColumn(app, myTable, col_name, editable, col_width)
            arguments (Input)
                app 
                myTable
                col_name char
                editable logical = false
                col_width = 'auto'
            end
            if isequal(length(myTable.ColumnName), 0)
                myTable.ColumnName = {col_name};
                myTable.ColumnEditable = editable;
                myTable.ColumnWidth = {col_width};
            else
                myTable.ColumnName = [myTable.ColumnName', {col_name}]';
                myTable.ColumnEditable = [myTable.ColumnEditable, editable];
                myTable.ColumnWidth = [myTable.ColumnWidth, {col_width}];
            end
        end
        
        function col_name = ProcessConfigColumn(app, column_string)
            tokens = regexp(column_string, '(\w+)\s*=\s*(\w+)\(([\w, ]*)\)', 'tokens');% Extract components from prompt

            tokens = tokens{1}; % Extract inner cell array
            col_name = tokens{1};      % column label
            func_to_run = tokens{2};    % function to run
            func_inputs = strtrim(tokens{3});  %function inputs
            func_inputs_arr = strtrim(strsplit(func_inputs, ','));
            % Construct the function string for str2func
            func_str = sprintf("@(%s) %s(%s)", func_inputs, func_to_run, func_inputs);
            
            new_row = {col_name, {func_str}, {func_inputs_arr}'};
            app.process_load_data_table = [app.process_load_data_table; new_row];
        end
       
        function addColumn(app, col)
            arguments (Input)
                app 
                col string
            end
            
            if ~ismember(col, app.data.Properties.VariableNames)
                app.data.(col) = cell(0,1);
            else
                error("added column %s has same name as internal column %s, please change either of these values", col,  app.data.Properties.VariableNames);
            end
        end
    end

    methods (Description= "data",Access = private) 
    
        function updateDataTable(app)
            app.visibleData.Data = app.data(:, app.visibleData.ColumnName);
        end
        
        function processLoadData(app, filenames, filepaths)
            mask = ismember(filenames, app.data.(app.colName('name')));        
            newData = table(filenames(~mask), filepaths(~mask), 'VariableNames', [app.colName("name"),app.colName("path")]);
            if height(newData) == 0
                return
            end
            newData.(app.colName("select")) = true(height(newData),1);
            newData.(app.colName("manual")) = false(height(newData),1);
            newData.(app.hColName("imgColor")) = repmat([0, 0, 0], height(newData), 1);
            newData.(app.colName("pivot")) = false(height(newData),1);
            newData.(app.hColName("tform")) = repmat(affinetform2d(), height(newData), 1);
            newData.(app.colName("ID")) = (height(app.data)+1 :height(app.data)+height(newData))';
            for i=1:height(app.process_load_data_table)

                columns = cell(1, length(app.process_load_data_table.input_cols{i}));
                for j=1:length(columns)
                    columns{j} = newData.(app.process_load_data_table.input_cols{i}{j});
                end
                handle = app.process_load_data_table.handles{i};
                newData.(app.process_load_data_table.output_cols{i}) = handle(columns{:});
            end
            
            
            for i=1:length(app.imageVersionDropDown.Items)
                col = app.imageVersionDropDown.Items{i};

                outView = @(x) affineOutputView(size(newData.(col + app.rawsuffix){x}),newData.(app.hColName("tform"))(x),"BoundsStyle","FollowOutput");
                arrayName = @(x) string(sprintf('%s [%d * %d]', class(x{:}), size(x{:},1), size(x{:},2)));
                
                newData.(col + app.viewsuffix) = arrayfun(outView, 1:height(newData))';
                newData.(col) = arrayfun(arrayName, newData.(col + app.rawsuffix));
            end
            
            toInit = setdiff(app.data.Properties.VariableNames, newData.Properties.VariableNames);
            for i=1:length(toInit)
                newData.(toInit{i}) = cell(height(newData), 1);
            end
            
            reorderedTable = newData(:, app.data.Properties.VariableNames);
            app.data = [app.data; reorderedTable];
            
            updateDataTable(app);
            calcImageCorrelation(app);
            app.resetSelMem();
        end
        
        function updateTForms(app, tforms, selectIDx)
            % just reasigns the transform for each selectIDx to the new one
            % also multiplies the new one with the old one in order to
            % preserve current registration; on the first iteration the
            % current tform is the identity matrix so the multiplication
            % results in the new one alone.
            if ~(length(tforms) == length(selectIDx))
                error("BUG: length of selected images does not equal length of returned registered transforms")
            end
            
            for i=1:length(selectIDx)
                newTform = affinetform2d(app.data.(app.hColName("tform"))(selectIDx(i)).A*tforms(i).A);
                app.data.(app.hColName("tform"))(selectIDx(i)) = newTform;
                
                for j=1:length(app.imageVersionDropDown.Items)
                    col = app.imageVersionDropDown.Items{j};
                    app.data.(col + app.viewsuffix)(selectIDx(i)) = affineOutputView(size(app.data.(col + app.rawsuffix){selectIDx(i)}),...
                                                                                    newTform, "BoundsStyle", "FollowOutput");
                end
            end
        end
        
        function resetID(app)
            IDs = 1:height(app.data);
            app.data.(app.colName("ID")) = IDs';
            app.updateDataTable();
        end
        
        function resetSelMem(app)
            app.selMemory.setLen(0);
            curSel = find(app.data.(app.colName("select")));
            app.selMemory.lengthen(curSel);
        end
    end

    methods (Description= "Image",Access = private)
        
        function resetImgColor(app, selectedImgs)
            % Recalculate colors to use to maximize contrast
            n = length(find(selectedImgs));
            
            if n==0
                colors = 0.7;
            elseif n==1
                colors = [0 1 1];
            elseif n==2
                colors = [0 1 1;...
                          1 0 0];
            elseif n==3
                colors = [0 0 1;...
                          0 1 0;...
                          1 0 0];
            else
                colors = flipud(cmapL([0 0 1; 0 1 1; 0 1 0; 1 0.7 0; 1 0 0; 0.7 0 1], n));
            end
            if strcmp(app.ColorSortingSwitch.Value, 'Color retain')
                % Use queue values as index to assign color
                selectedImgs = app.selMemory.vals;
                selectedImgs(selectedImgs==0) = [];
            end
            app.data.(app.hColName("imgColor"))(:,:) = 0.7;
            app.data.(app.hColName("imgColor"))(selectedImgs, :) = colors;
        end

        function updateSelectMem(app)
            vals = app.selMemory.setLen(app.Spinner.Value);
            if vals
                for i=1:length(vals)
                    try
                        app.data.(app.colName("select"))(vals(i)) = 0;
                    catch ME
                        disp(vals)
                        disp(class(vals))
                        disp(i)
                        rethrow(ME)
                    end
                end
            end
        end

        function updateImageTable(app)
            app.imageTable.Data = app.data(:, app.imageTable.ColumnName);
            for i=1:height(app.data)
                style = uistyle('BackgroundColor',app.data.(app.hColName("imgColor"))(i, :));
                if app.data.(app.colName("manual"))(i) & true % TODO IMPLEMENT MANUAL MOVING
                    addStyle(app.imageTable,style,'cell',[i 1; i 2; i 3])
                else
                    addStyle(app.imageTable,style,'cell',[i 1; i 2])
                    style = uistyle('BackgroundColor', [0.7 0.7 0.7]);
                    addStyle(app.imageTable,style,'cell',[i, 3])
                end
            end
        end
        
        function warpImgs(app, imageName, outputView)
            imgLabel = imageName + app.rawsuffix;
            
            for i = 1:height(app.data)
                app.data.(app.hColName("wImg")){i} = imwarp(app.data.(imgLabel){i}, app.data.(app.hColName("tform"))(i), "OutputView", outputView, "interp", "nearest");
            end
        end
        
        function plotImg(app)
           imgID = app.data.(app.colName("select"));
           if sum(imgID) == 0
               finalImg = zeros(app.outputView.ImageSize);
               imagesc(app.UIAxes, finalImg, 'HitTest', 'off');
               return
           end
           
           selectedImgs = app.data.(app.hColName("wImg"))(imgID);
           colors = app.data.(app.hColName("imgColor"))(imgID, :);
           
           finalImg  = CreateRGB2(selectedImgs, colors);
           imagesc(app.UIAxes, finalImg, 'HitTest', 'off');
        end
        
        function outputView = getImgRef(app, imgID, imgLabel)
            if sum(imgID) == 0 
                outputView = getLargestImgRef(app.data.(imgLabel)(:));
            elseif sum(imgID) == 1
                outputView = app.data.(imgLabel)(imgID);
            else
                outputView = getLargestImgRef(app.data.(imgLabel)(imgID));
            end
        end
        
        function setImg(app)
            if height(app.data) > 0
                imgLabel = app.imageVersionDropDown.Value + app.viewsuffix;
                
                imgID = app.data.(app.colName("select"));
                
                app.outputView = app.getImgRef(imgID, imgLabel);
                app.warpImgs(app.imageVersionDropDown.Value, app.outputView);
                app.plotImg();
            end
        end
        
        function calcImageCorrelation(app)
            if ~isempty(app.data.(app.hColName("wImg")){1})
                corrScore = BImgOverlapScore(app.data.(app.hColName("wImg")));
            else
                corrScore = BImgOverlapScore(app.data.(app.hColName("Image_raw")));
            end
            imagesc(app.UIAxes_corr, corrScore)
            set(app.UIAxes_corr, "YTick", 1:size(corrScore, 1), "YTickLabel", app.data.("IDName"),...
                                 "XTick", 1:size(corrScore, 2))
            ylim(app.UIAxes_corr, [0.5, size(corrScore, 1)+0.5])
            xlim(app.UIAxes_corr, [0.5, size(corrScore, 2)+0.5])

            idx = true(size(corrScore));
            idx(triu(idx)) = false;
            
            maxVali = max(corrScore(idx));
            maxVal = max(maxVali, app.corr_maxVal, 'omitmissing');
            if maxVal ~= app.corr_maxVal
                app.corr_maxVal = maxVal;
            end
            app.Label_corr.Text = {sprintf('min correlation: %.2f', min(corrScore(idx)));...
                                   sprintf('max correlation: %.2f max ever: %.2f', maxVali, app.corr_maxVal)};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %NOT NECESSARY FOR APPS, AS IT ADDS CONTEXT BY DEFAULT
            %app.initWorkingDir();
            
            if ~ispref('imageReg2P', 'config_name')
                app.config = resetConfig();
            else
                config_name = getpref('imageReg2P', 'config_name');
                app.config = getpref('imageReg2P', config_name);
            end
            
            app.initData();            
            app.selMemory = basicQueue(); %memory of order in which images are selected
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            
            set(app.UIAxesScrollManual, 'YDir', 'reverse')
            xlim(app.UIAxesScrollManual, [0 3])
            ylim(app.UIAxesScrollManual, [0 3])

        end

        % Button pushed function: LoadfolderButton
        function LoadDataDir(app, event)
            [filenames, filepaths] = fetchDir();
            if ~ isequal(length(filenames), 0)
                processLoadData(app, filenames, filepaths);
            end
            figure(app.UIFigure) % refocus on figure
        end

        % Button pushed function: LoadfilesButton
        function LoadDataFile(app, event)
            [filenames, filepaths] = fetchFile('*_SPSIG.mat');
            if ~ isequal(length(filenames), 0)
                processLoadData(app, filenames, filepaths);
            end
            figure(app.UIFigure) % refocus on figure

        end

        % Button pushed function: RemoveselectedrowsButton
        function removeSelectedRows(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                name = 'imageTable';
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Data')
                name =  'visibleData';
            end
            
            if ~ strcmp(app.(name).SelectionType, 'row')
                error("Selection type is not row");
            end

            app.data(app.(name).Selection, :) = [];
            app.selMemory.bulkRemoveAndReassign(app.(name).Selection);
            app.(name).Selection = [];

            app.resetID(); %no need to updateDataTab as resetID does this

            if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                app.updateImageTable();
                if height(app.data) >= 1
                    app.Spinner.Limits = [1 height(app.data)];
                end
                plotImg(app);
                
            elseif strcmp(app.TabGroup.SelectedTab.Title,'Data')
                app.updateDataTable();
            end
        end

        % Cell edit callback: visibleData
        function dataPivot(app, event)
            select = app.colName("select");
            pivot = app.colName("pivot");
            row = event.Indices(1);
            col = event.Indices(2);
            prevRow = app.previousPivotIndex;
            prevSel = app.prevSelect;
            
            if app.visibleData.ColumnName(col) == pivot
                if event.NewData == 1
                    %turn previous pivot false if it is true
                    if prevRow == row
                        return
                    end
                    if prevRow
                        app.data.(pivot)(prevRow) = 0;
                        if prevSel
                            app.data.(select)(prevRow) = 1;
                        else
                            app.selMemory.remove(prevRow);
                        end
                    end
                    
                    %turn current select false if it is true
                    app.previousPivotIndex = row;
                    if app.data.(select)(row)
                        app.data.(select)(row) = 0;
                        app.prevSelect = 1;
                    else
                        app.prevSelect = 0;
                        app.selMemory.enqueue(row);
                    end
                else
                    %remove prev pivot memory and return select value to
                    %what it was
                    app.previousPivotIndex = 0;
                    if prevSel
                        app.data.(select)(row) = 1;
                        app.prevSelect = 0;
                    else
                        app.selMemory.remove(row);
                    end
                    
                    %dont need to check for case that prevrow = row, as by
                    %definition for the input to be 0 and the pivot, it
                    %mustve been the only true value in that column before
                end
                app.data.(pivot)(row) = event.NewData;
                
            elseif app.visibleData.ColumnName(col) == select
                app.data.(select)(row) = event.NewData;
                if event.NewData
                    if row == app.previousPivotIndex
                        app.data.(pivot)(row) = 0;
                        app.previousPivotIndex = 0;
                    else
                        app.selMemory.enqueue(row);
                    end
                else
                    app.selMemory.remove(row);
                end
            end
            updateDataTable(app);
        end

        % Button pushed function: RegisterimagesButton, 
        % ...and 1 other component
        function runRegistration(app, event)
           progressBar = waitbar(0, "Initialising ...", 'Name', 'Closing this cancels the registration');
           %get info on selected images, and image type
           imgLabel = app.imageVersionDropDown.Value;
           selectID = app.data.(app.colName("select"));
           pivotID = app.data.(app.colName("pivot"));
           idx = find((selectID | pivotID));

           outView = app.getImgRef(idx, (imgLabel + app.viewsuffix));
            %{
           To enable continuous refinement we need to let successive
           registrations build upon already found solutions
           Therefore:
                apply the current transformation to the
                images before sending to registerImgs, then
                multiply the output best found tform to the
                current existing tforms, for the first iteration this is
                overhead but has no impact as current tform are identity
                matrices
           %}
           warpImgs(app, imgLabel, outView);
           selectedImgs = app.data.(app.hColName("wImg"))(selectID);
           pivot = app.data.(app.hColName("wImg"))(pivotID);
           
           if isempty(pivot)
               pivot = {0};
           end
           
           regInfo = app.config.register;
           %run optimisation
           transforms = registerImgs(selectedImgs, pivot, ...
               regInfo.metric, regInfo.optimiser, levels = regInfo.levels, ...
               transform = regInfo.transform, crossCTransform= regInfo.crossCTransform, ...
               progressBar=progressBar);
           waitbar(100, progressBar, "Finishing ..."); 
           
           %populate the table with the transforms
           updateTForms(app, transforms, idx);
           close(progressBar)
           setImg(app);
           calcImageCorrelation(app);
        end

        % Button pushed function: ResettransformsButton, 
        % ...and 1 other component
        function resetTforms(app, event)
            selectID = app.data.(app.colName("select"));
            pivotID = app.data.(app.colName("pivot"));
            idx = find((selectID | pivotID));
            for i=1:length(idx)
                app.data.(app.hColName("tform"))(idx(i)) = affinetform2d();
            end
            None = struct;
            dropDownImgRefresh(app, None)
            calcImageCorrelation(app)
            if strcmp(app.TabGroup2.SelectedTab.Title, 'Manual')
                UpdateManualMoveTab(app, [])
            end
        end

        % Button down function: DataTab
        function updateDataTab(app, event)
            %move buttons from image tab to data tab
            app.imageVersionDropDown.Parent = app.dataButtonGrid;
            % app.imageVersionDropDown.Layout.Row = 3;
            % app.imageVersionDropDown.Layout.Column = 1;
            %update table
            idx = find(app.data.(app.colName("pivot")));
            if idx
                if app.data.(app.colName("select"))(idx) == 1
                    app.data.(app.colName("select"))(idx) = 0;
                else
                    app.selMemory.enqueue(idx);
                end
            end
            
            updateDataTable(app);
        end

        % Button down function: ImageTab
        function updateImageTab(app, event)
            idx = find(app.data.(app.colName("pivot")));
            if idx
                app.data.(app.colName("select"))(idx) = 1;
            end
            %move buttons from data tab to image tab
            app.imageVersionDropDown.Parent = app.imageButtonGrid;
            
            %set bottom limit, otherwise clicking on select throws an error
            %when enqueueing on a queue of size 0
            if height(app.data) >= 1
                app.Spinner.Limits = [1 height(app.data)];
            end
            app.Spinner.Value = max([sum(app.data.(app.colName("select")))], app.Spinner.Limits(1));
            
            
            vals = app.selMemory.setLen(max([sum(app.data.(app.colName("select"))),1]));
            if vals
                error("there was an error with the select memory, values were discarded which means a bug")
            end
            app.UseallCheckBox.Value = false;
            
            %load in image viewer uitable
            if height(app.data) >= 1
                resetImgColor(app, true(height(app.data),1));
                updateImageTable(app);
                
                %plot images
                setImg(app);
            end
        end

        % Cell edit callback: imageTable
        function imageSelect(app, event)
            if event.Indices(2) == 1 % Change pivot
                col = app.colName("pivot");
                test.Indices(1) = event.Indices(1);
                test.Indices(2) = find(strcmp(app.visibleData.ColumnName, col));
                test.NewData = event.NewData;
                app.dataPivot(test)
                
                if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                     idx = find(app.data.(app.colName("pivot")));      
                     app.data.(app.colName("select"))(idx) = true;
                     app.updateImageTable();
                     app.plotImg();
                end
                
            elseif event.Indices(2) == 2 % Change displayed recordings
                select = app.colName("select");
                row = event.Indices(1);
                app.data.(select)(row) = event.NewData;
                if event.NewData
                    if strcmp(app.RemovingOldestSwitch.Value, 'Removing newest')
                        removeSide = 'dequeue';
                    else % remove oldest
                        removeSide = 'pop';
                    end
                    val = app.selMemory.enqueue(row, removeSide);
                    if val
                        app.data.(select)(val) = false;
                    end
                else
                    try
                        app.selMemory.remove(row);
                    catch ME
                        disp("TRY CATCH BLOCK:")
                        disp(row)
                        disp(event.NewData)
                        temp = app.selMemory.getState();
                        disp(temp)
                        rethrow(ME)
                    end
                end
                if app.ResetcolorsCheckBox.Value
                    resetColor(app, [])
                end
                app.selMemory
                
                updateImageTable(app);
                plotImg(app);

            elseif event.Indices(2) == 3 % Change manually moving recording selection
                app.data.(app.colName("manual")) = event.Source.Data.(app.colName("manual"));
                updateImageTable(app);
                UpdateManualMoveTab(app, []);
            else
                error("Clicked column according to event.Indices(2), is not supposed to be editable")
            end
        end

        % Value changed function: UseallCheckBox
        function UseallCheckBoxValueChanged(app, event)
            if event.Value == 1
                app.selMemState = app.selMemory.getState();
                newIDX = setdiff((1:height(app.data)), app.selMemory.vals);
                app.data.(app.colName("select"))(newIDX) = 1;
                app.selMemory.lengthen(newIDX);
                
                app.Spinner.Value = app.Spinner.Limits(2);
            else
                app.selMemory.loadState(app.selMemState);
                newIDX = setdiff((1:height(app.data)), app.selMemory.vals);
                app.data.(app.colName("select"))(newIDX) = 0;
                app.Spinner.Value = app.selMemState.size;
            end

            if app.ResetcolorsCheckBox.Value
                resetColor(app, [])
            end
            updateImageTable(app);
            plotImg(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            % Spinner to select number of files to show
            if event.PreviousValue == app.Spinner.Limits(2) && event.Value < app.Spinner.Limits(2)
                app.UseallCheckBox.Value = false;
            end
            updateSelectMem(app);
            if app.ResetcolorsCheckBox.Value
                resetColor(app, [])
            end
            updateImageTable(app);
            plotImg(app);
        end

        % Button pushed function: RecenterviewButton
        function RecenterviewButtonPushed(app, event)
            setImg(app);
        end

        % Value changed function: imageVersionDropDown
        function dropDownImgRefresh(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title,'Image')
                setImg(app)
            end
        end

        % Value changed function: ColorSortingSwitch, 
        % ...and 2 other components
        function resetColor(app, event)
            selection = app.data.(app.colName("select"));
            resetImgColor(app, selection);
            updateImageTable(app);
            plotImg(app);
% set(app.UIAxesScrollManual, 'CameraPosition')
        end

        % Button pushed function: SaveButton, SaveButton_2
        function save(app, event)
            % hardcoded for Mask at the moment
            outputTable = app.data;
            if ismember('Image', outputTable.Properties.VariableNames)
                outputTable.('Image' + app.viewsuffix) = [];
            end
            if isKey(app.colName, "Image")
                outputTable.('Image') = [];
                outputTable.("Image_warped") = warpImagesfromTforms(outputTable.tForm, app.data.("Image" + app.rawsuffix));
            end
            if ismember('warpedImg', outputTable.Properties.VariableNames)
                outputTable.('warpedImg') = [];
            end
            if isKey(app.colName, "Mask")
                outputTable.("Mask") = [];
                outputTable.("Mask" + app.viewsuffix) = [];
                outputTable.("Mask_warped") = warpImagesfromTforms(outputTable.tForm, app.data.("Mask" + app.rawsuffix));
            end

            assignin("base", "registration_data", outputTable);
        end

        % Window scroll wheel function: UIFigure
        function ScrollChangeValue(app, event)
            % clc
            
            % verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            % pos = app.UITableTransform.Position;
            % set(app.UITableTransform, 'Position', [0, pos(2), 1, pos(4)]);
            
            idx = find(app.data.(app.colName("manual")));
            n = size(app.data.(app.hColName("tform"))(idx(1)).A);
            cur = app.UIAxesScrollManual.CurrentPoint(1, [1 2]);
            cur = ceil(cur);
            if all(cur>0) && (cur(1)<=n(2) && cur(2)<n(1))
                x = cur(1);
                y = cur(2);
                % fprintf('ya%d, %d. scrollCount%d, amount%d\n', cur(1), cur(2), verticalScrollCount, verticalScrollAmount)
                speedofChange = [0.01, 0.01, 1;...
                                 0.01, 0.01, -1;...
                                 0,   0,   0];
                change = speedofChange(y,x) * -verticalScrollCount;
                for i = idx'
                    app.data.(app.hColName("tform"))(i).A(y,x) = app.data.(app.hColName("tform"))(i).A(y,x) + change; 
                end
                app.UITableTransform.Data = app.data.(app.hColName("tform"))(idx(1)).A;

                setImg(app)
                calcImageCorrelation(app);
            end
        end

        % Button down function: ManualTab
        function UpdateManualMoveTab(app, event)
            % Executes when the Manual registration tab is opened, to show
            % the data of the 1st manual moving image and scale the table
            toMove = app.data.(app.colName("manual"));
            if any(toMove)
                clc
                idx = find(toMove);
                app.UITableTransform.Data = app.data.(app.hColName("tform"))(idx(1)).A;
                app.UITableTransform.ColumnName = {'x', 'y', 'z'};
            else
                app.UITableTransform.Data = {'Select'; 'manual'; 'recording'};
            end
            % Fit the content of the table so the scrolling callback works
            % correctly. Has to fit with UIAxesScrollManual axis
            set(app.UITableTransform, 'Units', 'pixels');
            pos = app.UITableTransform.Position;
            nCols = size(app.UITableTransform.Data, 2);
            colWidths = repmat({pos(3)/nCols}, [1 3]);
            nRows = size(app.UITableTransform.Data, 1);
            rowHeight = (pos(4)-20)/nRows/2.5;
            app.UITableTransform.ColumnWidth = colWidths;
            app.UITableTransform.FontSize = rowHeight;
            app.UITableTransform.ColumnFormat = repmat({'short'}, [1, nCols]);
            % Change the axis position to ignore uitable scrollbar and labels
            app.UIAxesScrollManual.Position = [pos(1), pos(2)+12, pos(3), pos(4)-33];
            % % Testing position of UIaxes
            % set(app.UIAxesScrollManual, 'Units', 'pixels', 'Visible', 'on');
            % set(app.UITableTransform, 'Units', 'pixels', 'Visible', 'off');
            % pause(2)
            % set(app.UITableTransform, 'Units', 'pixels', 'Visible', 'on');
            % set(app.UIAxesScrollManual, 'Units', 'pixels', 'Visible', 'off');
        end

        % Cell edit callback: UITableTransform
        function UITableTransformCellEdit(app, event)
            % edits the transform values
            indices = event.Indices;
            newData = event.NewData;
            oldData = event.PreviousData;

            idx = find(app.data.(app.colName("manual")));

            app.data.(app.hColName("tform"))(idx(1)).A(indices(1), indices(2)) = newData;
            % Apply the same change of value to the other manually moving images
            change = newData - oldData;
            idx(1) = [];
            if ~isempty(idx)
                for i = idx
                    newVal = app.data.(app.hColName("tform"))(i).A(indices(1), indices(2)) + change;
                    app.data.(app.hColName("tform"))(i).A(indices(1), indices(2)) = newVal;
                end
            end

            setImg(app)
            calcImageCorrelation(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            fprintf('Button down :D!')
            idx = find(app.data.(app.colName("manual")));
            if strcmp(app.TabGroup2.SelectedTab.Title, 'Ctrl pts') && ~isempty(idx)
                movingPoints = app.data(idx(1)).movePoints;
                fixedPoints = app.data(idx(1)).fixedPoints;
                if event.button==1
                    % add point
                    if fixedPoints == movingPoints
                        % Add moving point
                        
                    else
                        % Add fixed point
                        
                        % Apply the control points if possible
                        nPts = size(ctrlPts, 1);
                        if ~ismember(nPts, [0, 2])
                            % Update the transform
                            if nPts==1
                                % Only translate
                            else
                                % 
                            end
                            for i = idx
                                
                            end
                        end
                    end
                else
                    % Remove closest reference/ control point
                    
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @ScrollChangeValue, true);
            app.GridLayout9 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout9);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.DataTab.ButtonDownFcn = createCallbackFcn(app, @updateDataTab, true);
            app.dataGrid = uigridlayout(app.DataTab);
            app.visibleData = uitable(app.dataGrid);
            app.visibleData.ColumnName = '';
            app.visibleData.ColumnRearrangeable = 'on';
            app.visibleData.RowName = {};
            app.visibleData.SelectionType = 'row';
            app.visibleData.CellEditCallback = createCallbackFcn(app, @dataPivot, true);
            app.dataButtonGrid = uigridlayout(app.dataGrid);
            app.SaveButton = uibutton(app.dataButtonGrid, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.ResettransformsButton_2 = uibutton(app.dataButtonGrid, 'push');
            app.ResettransformsButton_2.ButtonPushedFcn = createCallbackFcn(app, @resetTforms, true);
            app.ResettransformsButton_2.Text = 'Reset transforms';
            app.RegisterimagesButton_2 = uibutton(app.dataButtonGrid, 'push');
            app.RegisterimagesButton_2.ButtonPushedFcn = createCallbackFcn(app, @runRegistration, true);
            app.RegisterimagesButton_2.Text = 'Register images';
            app.imageVersionDropDown = uidropdown(app.dataButtonGrid);
            app.imageVersionDropDown.Items = {};
            app.imageVersionDropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownImgRefresh, true);
            app.imageVersionDropDown.Placeholder = 'no image function given';
            app.imageVersionDropDown.Value = {};
            app.RemoveselectedrowsButton = uibutton(app.dataButtonGrid, 'push');
            app.RemoveselectedrowsButton.ButtonPushedFcn = createCallbackFcn(app, @removeSelectedRows, true);
            app.RemoveselectedrowsButton.Text = 'Remove selected rows';
            app.LoadfilesButton = uibutton(app.dataButtonGrid, 'push');
            app.LoadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataFile, true);
            app.LoadfilesButton.Text = 'Load files';
            app.LoadfolderButton = uibutton(app.dataButtonGrid, 'push');
            app.LoadfolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataDir, true);
            app.LoadfolderButton.Text = 'Load folder';
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.ImageTab.ButtonDownFcn = createCallbackFcn(app, @updateImageTab, true);
            app.imageGrid = uigridlayout(app.ImageTab);
            app.UIAxes = uiaxes(app.imageGrid);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.imageButtonGrid = uigridlayout(app.imageGrid);
            app.SaveButton_2 = uibutton(app.imageButtonGrid, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton_2.Text = 'Save';
            app.ResettransformsButton = uibutton(app.imageButtonGrid, 'push');
            app.ResettransformsButton.ButtonPushedFcn = createCallbackFcn(app, @resetTforms, true);
            app.ResettransformsButton.Text = 'Reset transforms';
            app.SelectionAmountButtonGroup = uibuttongroup(app.imageButtonGrid);
            app.SelectionAmountButtonGroup.Title = 'Selection Amount';
            app.ColorSortingSwitch = uiswitch(app.SelectionAmountButtonGroup, 'slider');
            app.ColorSortingSwitch.Items = {'Color sort', 'Color retain'};
            app.ColorSortingSwitch.ValueChangedFcn = createCallbackFcn(app, @resetColor, true);
            app.ColorSortingSwitch.Value = 'Color sort';
            app.ResetcolorsCheckBox = uicheckbox(app.SelectionAmountButtonGroup);
            app.ResetcolorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @resetColor, true);
            app.ResetcolorsCheckBox.Text = 'Reset colors';
            app.RemovingOldestSwitch = uiswitch(app.SelectionAmountButtonGroup, 'slider');
            app.RemovingOldestSwitch.Items = {'Removing oldest', 'Removing newest'};
            app.RemovingOldestSwitch.ValueChangedFcn = createCallbackFcn(app, @resetColor, true);
            app.RemovingOldestSwitch.Value = 'Removing oldest';
            app.UseallCheckBox = uicheckbox(app.SelectionAmountButtonGroup);
            app.UseallCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseallCheckBoxValueChanged, true);
            app.UseallCheckBox.Text = 'Use all';
            app.Spinner = uispinner(app.SelectionAmountButtonGroup);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.TabGroup2 = uitabgroup(app.imageButtonGrid);
            app.AutoTab = uitab(app.TabGroup2);
            app.AutoTab.Title = 'Auto';
            app.GridLayout_AutoMove = uigridlayout(app.AutoTab);
            app.RegisterimagesButton = uibutton(app.GridLayout_AutoMove, 'push');
            app.RegisterimagesButton.ButtonPushedFcn = createCallbackFcn(app, @runRegistration, true);
            app.RegisterimagesButton.Text = 'Register images';
            app.RecenterviewButton = uibutton(app.GridLayout_AutoMove, 'push');
            app.RecenterviewButton.ButtonPushedFcn = createCallbackFcn(app, @RecenterviewButtonPushed, true);
            app.RecenterviewButton.Text = 'Recenter view';
            app.ManualTab = uitab(app.TabGroup2);
            app.ManualTab.Title = 'Manual';
            app.ManualTab.ButtonDownFcn = createCallbackFcn(app, @UpdateManualMoveTab, true);
            app.GridLayoutManualMove = uigridlayout(app.ManualTab);
            app.UIAxesScrollManual = uiaxes(app.GridLayoutManualMove);
            app.UIAxesScrollManual.XTick = [];
            app.UIAxesScrollManual.YTick = [];
            app.UIAxesScrollManual.Visible = 'off';
            app.UITableTransform = uitable(app.GridLayoutManualMove);
            app.UITableTransform.ColumnName = {'1'; '2'; '3'};
            app.UITableTransform.RowName = {};
            app.UITableTransform.ColumnEditable = true;
            app.UITableTransform.CellEditCallback = createCallbackFcn(app, @UITableTransformCellEdit, true);
            app.CtrlptsTab = uitab(app.TabGroup2);
            app.CtrlptsTab.Title = 'Ctrl pts';
            app.Label2 = uilabel(app.CtrlptsTab);
            app.Label2.Text = {'Left-click on main image to '; 'set moving point '; 'Left-click again to set reference point'; 'to which the moving point will '; 'attempt to move'; ''; 'Use 1, 3 or more control points.'; ''; 'Right click to delete closest control point'};
            app.imageTable = uitable(app.imageGrid);
            app.imageTable.ColumnName = '';
            app.imageTable.RowName = {};
            app.imageTable.CellEditCallback = createCallbackFcn(app, @imageSelect, true);
            app.GridLayout_corr = uigridlayout(app.imageGrid);
            app.UIAxes_corr = uiaxes(app.GridLayout_corr);
            app.Label_corr = uilabel(app.GridLayout_corr);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imgRegMain

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end